classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        VstupyMenu                     matlab.ui.container.Menu
        CasyMenu                       matlab.ui.container.Menu
        XLSX_suborMenu                 matlab.ui.container.Menu
        CSV_suborMenu                  matlab.ui.container.Menu
        MAT_suborMenu                  matlab.ui.container.Menu
        PaketyzMenu                    matlab.ui.container.Menu
        XLSX_paketyMenu                matlab.ui.container.Menu
        CSV_paketyMenu                 matlab.ui.container.Menu
        MAT_paketyMenu                 matlab.ui.container.Menu
        PCAPsuborMenu                  matlab.ui.container.Menu
        FunkcionalityMenu              matlab.ui.container.Menu
        DetaielnejsiVystupMenu         matlab.ui.container.Menu
        UpravaVstupuMenu               matlab.ui.container.Menu
        MenuFunkcie2D                  matlab.ui.container.Menu
        Menu2DPriemer                  matlab.ui.container.Menu
        Menu2DOdchylka                 matlab.ui.container.Menu
        Menu2DVariabilnost             matlab.ui.container.Menu
        Menu2DSikmost                  matlab.ui.container.Menu
        Menu2DSpicatost                matlab.ui.container.Menu
        Menu2DHurst                    matlab.ui.container.Menu
        Menu2DEntropia                 matlab.ui.container.Menu
        Menu2DEnergia                  matlab.ui.container.Menu
        Menu2DAutoregresia             matlab.ui.container.Menu
        Menu2DKorelacia                matlab.ui.container.Menu
        Menu2DDivergencia              matlab.ui.container.Menu
        Funkcie3DMenu                  matlab.ui.container.Menu
        Menu3DPriemer                  matlab.ui.container.Menu
        Menu3DOdchylka                 matlab.ui.container.Menu
        Menu3DVariabilnost             matlab.ui.container.Menu
        Menu3DSikmost                  matlab.ui.container.Menu
        Menu3DSpicatost                matlab.ui.container.Menu
        Menu3DHurst                    matlab.ui.container.Menu
        Menu3DEntropia                 matlab.ui.container.Menu
        Menu3DEnergia                  matlab.ui.container.Menu
        Menu3DAutoregresia             matlab.ui.container.Menu
        Menu3DKorelacia                matlab.ui.container.Menu
        Menu3DDivergencia              matlab.ui.container.Menu
        Panel_DetaielnejsiVystup       matlab.ui.container.Panel
        ButtonDetail_ClearGraf         matlab.ui.control.Button
        ButtonDetail_Resetovat         matlab.ui.control.Button
        ButtonDetail_Vypocitat         matlab.ui.control.Button
        CheckBoxDetail_DH              matlab.ui.control.CheckBox
        CheckBoxDetail_HH              matlab.ui.control.CheckBox
        CheckBoxDetail_Odchylka        matlab.ui.control.CheckBox
        CheckBoxDetail_Priemer         matlab.ui.control.CheckBox
        LabelDetail_CheckBox           matlab.ui.control.Label
        EditFieldDetail_Sigma          matlab.ui.control.NumericEditField
        ZadajtevekosoknapreHHaDHsigmyEditFieldLabel  matlab.ui.control.Label
        EditFieldDetail_Priemer        matlab.ui.control.NumericEditField
        ZadajtevekosoknaprepohyblivpriemerEditFieldLabel  matlab.ui.control.Label
        EditFieldDetail_Odchylka       matlab.ui.control.NumericEditField
        ZadajtevekosoknaprepohyblivodchlkuLabel  matlab.ui.control.Label
        LabelDetail_NacitanySubor      matlab.ui.control.Label
        LabelDetailInfo_Vstup          matlab.ui.control.Label
        ButtonDetail_NacitatVstup      matlab.ui.control.Button
        ButtonDetail_Ukoncit           matlab.ui.control.Button
        UIAxesDetail                   matlab.ui.control.UIAxes
        Panel_2DFunkcie                matlab.ui.container.Panel
        EditFieldVynasob2D             matlab.ui.control.NumericEditField
        ButtonUlozParameter2D          matlab.ui.control.Button
        ButtonVynasobParameter2D       matlab.ui.control.Button
        ButtonVypocitat2D              matlab.ui.control.Button
        LabelHodnotaOkna2D             matlab.ui.control.Label
        EditField2D_Hodnota            matlab.ui.control.NumericEditField
        HodnotaLabel                   matlab.ui.control.Label
        ButtonNacitatVstup2D           matlab.ui.control.Button
        Label2DNacitanySubor           matlab.ui.control.Label
        Label2DNacitanySuborInfo       matlab.ui.control.Label
        Button2DFunkcie_Ukoncit        matlab.ui.control.Button
        UIAxes2DFunkciaView            matlab.ui.control.UIAxes
        UIAxes2DFunkcia                matlab.ui.control.UIAxes
        Panel_3DFunkcie                matlab.ui.container.Panel
        ButtonVynasobParameter3D       matlab.ui.control.Button
        EditFieldVynasob3D             matlab.ui.control.NumericEditField
        ButtonVypocitat3D              matlab.ui.control.Button
        EditField_Hodnota5             matlab.ui.control.NumericEditField
        Hodnota5EditFieldLabel         matlab.ui.control.Label
        EditField_Hodnota4             matlab.ui.control.NumericEditField
        Hodnota4EditFieldLabel         matlab.ui.control.Label
        EditField_Hodnota3             matlab.ui.control.NumericEditField
        Hodnota3EditFieldLabel         matlab.ui.control.Label
        EditField_Hodnota2             matlab.ui.control.NumericEditField
        Hodnota2EditFieldLabel         matlab.ui.control.Label
        Zadajtehodnotyprevpoetmin1max5Label  matlab.ui.control.Label
        EditField_Hodnota1             matlab.ui.control.NumericEditField
        Hodnota1EditFieldLabel         matlab.ui.control.Label
        ButtonNacitatVstup3D           matlab.ui.control.Button
        LabelNacitanySubor             matlab.ui.control.Label
        LabelNacitanySuborInfo         matlab.ui.control.Label
        Button3DFunkcie_Ukoncit        matlab.ui.control.Button
        UIAxes3DViewFunkcia            matlab.ui.control.UIAxes
        UIAxes3DFunkcia                matlab.ui.control.UIAxes
        UlozTimestampButton            matlab.ui.control.Button
        UlozPacketLengthButton         matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        NatansborEditFieldLabel        matlab.ui.control.Label
        UlozPrirastkyCasuButton        matlab.ui.control.Button
        UlozkBPrirastkyButton          matlab.ui.control.Button
        Panel_UpravaVstupu             matlab.ui.container.Panel
        NacitatVstupButton             matlab.ui.control.Button
        UkoniButton                    matlab.ui.control.Button
        Panel_StrihajPo                matlab.ui.control.NumericEditField
        Panel_dvojbodka                matlab.ui.control.Label
        Panel_StrihajOd                matlab.ui.control.NumericEditField
        VystrihniButton                matlab.ui.control.Button
        Panel_UIAxes                   matlab.ui.control.UIAxes
        PrepocitajButton               matlab.ui.control.Button
        ButtonUlozFigure_a             matlab.ui.control.Button
        ButtonZobrazHistogram_a        matlab.ui.control.Button
        EditField_Histogram_a          matlab.ui.control.NumericEditField
        ButtonZobrazHistogram_b        matlab.ui.control.Button
        EditField_Histogram_b          matlab.ui.control.NumericEditField
        ButtonUlozFigure_b             matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        EditField_NacitanySubor        matlab.ui.control.EditField
        EditField_LengthOfData         matlab.ui.control.EditField
        VekosnatanchdtLabel            matlab.ui.control.Label
        NovvekospresamplewindowLabel   matlab.ui.control.Label
        EditField_MeanOfTimes          matlab.ui.control.EditField
        EditField_MeanPackets          matlab.ui.control.EditField
        EditField_StandardDeviation    matlab.ui.control.EditField
        EditField_StandardDeviation_2  matlab.ui.control.EditField
        EditField_SampleWindow         matlab.ui.control.EditField
        EditField_ChangeSampleWindow   matlab.ui.control.NumericEditField
        tandardnodchlkaLabel_2         matlab.ui.control.Label
        tandardnodchlkaLabel_3         matlab.ui.control.Label
        PriemerLabel_2                 matlab.ui.control.Label
        tandardnodchlkaLabel           matlab.ui.control.Label
        PriemerLabel                   matlab.ui.control.Label
        UIAxes_Prirastky               matlab.ui.control.UIAxes
        UIAxes_KB                      matlab.ui.control.UIAxes
        UIAxes_Te                      matlab.ui.control.UIAxes
        UIAxes_Kumulativ_Pakety        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        filename,path;
        Timestamp;              % ulozene data s casmi
        Pakety;                 % ulozene data s casmi a paketmi
        PacketLength;           % ulozene data s velkostami paketov
        Time_figure;            % ulozena figure prirastkov a
        Packet_figure;          % ulozena figure prirastkov kB b
        file;                   % subor
        N;                      % velkost PCAP suboru
        value;                  % checkbox value
        te;                     % medzery
        T2;                     % vektor nacitanych dat (casy, pakety)
        data1;                  % pomocny parameter pri nacitavani dat
        x,y;
        dh,hh;                  % dolna a horna hranica pre sigmu
        sigma;                  % sigma
        scaleFrom;              % os od
        scaleTo;                % os do
        standardDeviation;      % standardna odchylka
        priemer;                % priemer
        visibilityOfCheckBoxHH; % pomocna premenna pre checkbox
        NumberOfRows;           % pocet riadkov nacitanych dat
        meanOfTimes;            
        stdOfTimes;   
        meanOfPackets;
        stdOfPackets;
        vstupneData;            % nazov suboru, ktory sa vlozil ako vstup

        importovaneData;        % data, ktore sa importuje do panelu pre upravovanie importu
        vystrihnuteData;        % data, ktore nam vznikli po uprave v paneli pre upravovanie importu

        dataline;               % vykresluje hodnoty pri prepocitavani, aby uzivatel vedel kolko % je nacitanych
        
        pocet;
        okno;
        what_value;             % hodnota ktora urci ake okno pouzit na vypocet
                                % app.what_value == 1 -> berieme okno z
                                % app.UIFigure = EditField_ChangeSampleWindow.Value
                                % app.what_value == 2 -> berieme okno z
                                % app.Panel_DetaielnejsiVystup = EditFieldDetail_Okno.Value

        what_casy_value;        % hodnota: z akeho suboru sa loadnu casy
                                % app.what_casy_value == 1 -> XLSX subor
                                % app.what_casy_value == 2 -> CSV subor
                                % app.what_casy_value == 3 -> MAT subor

        what_pcap_value;        % hodnota ci sa data nacitali z pcap suboru
                                % app.what_pcap_value == 1 -> je to pcap subor
                                % app.what_pcap_value == 0 -> nie je to pcap subor
        
        what_pakety_value;      % hodnota: z akeho suboru sa nacitali pakety
                                % app.what_pakety_value  == 1 -> XLSX subor
                                % app.what_pakety_value  == 2 -> CSV subor
                                % app.what_pakety_value  == 3 -> MAT subor

        % sloter parameters
        d;                      % timestamp variable
        val;                    % d z GUI sa sem ulozi
        a;                      % prirastky casu
        b;                      % prirastky paketov
        T,I,P;                  % pomocne parametre

        filenameFunkcie;
        pathFunkcie;
        vstupneDataPreParameter;
        func_param;     % 1 = priemer, 2 = odchylka, 3 = variabilnost,
                        % 4 = sikmost, 5 = spicatost, 6 = hurst,
                        % 7 = entropia, 8 = energia, 9 = autoregresia, 
                        % 10 = korelacia, 11 = divergencia

        param_v;        % v nej su ulozene vypocitane hodnoty podla zvolenej 2D funkcie
        param3D_v;      % v nej su ulozene vypocitane hodnoty podla zvolenej 3D funkcie
        metoda1_2;      % 1 = jednoparametricka, 2 = dvojparametricka
        typMetody;      % 1 = 2D, 2 = 3D, 3 = Detaielne prirastky

    end
    
    properties (Access = public)

    end  
    
    methods (Access = public)       

        function Sloter_function(app, ~)

            if app.what_value == 1
                app.val = app.EditField_ChangeSampleWindow.Value;
                app.EditField_SampleWindow.Value = string(app.val);                
            end

            if app.what_value == 2
                app.val = app.EditFieldDetail_Okno.Value;
            end
            
            
            %if app.what_casy_value == 1 || app.what_casy_value == 2 || app.what_casy_value == 3
            if all(app.what_casy_value == 1)||all(app.what_casy_value == 2)||all(app.what_casy_value == 3)
                app.Sloter_bez_paketov_function;
            else
                datalineSloter = uiprogressdlg(app.UIFigure,'Title','Slotovanie','Icon','membrane.png', ...
                    'Interpreter','html','Cancelable','on','CancelText','Cancel');
                datalineSloter.Message = 'Prebieha slotovanie vašich dát...';                

                app.d = app.val; %timeslot
                sloter_value = 1;
                % ziskame si prirastky a a prirastky b pomocou funkcie
                % sloter vdaka externemu suboru sloter.m a ulozime si ich
                % do tabulky sloter_values odkial ich potom presunieme do
                % jednotlivych pramennych app.a a app.b
                sloter_values = sloter(sloter_value,app.d,app.N,app.te,app.PacketLength);
                app.a = table2array(sloter_values(:,1));
                app.b = table2array(sloter_values(:,2));

                close(datalineSloter);
            end
            
        end

        function Sloter_bez_paketov_function(app, ~)

            if app.what_value == 2
                app.val = app.EditFieldDetail_Okno.Value;
            else
                app.val = app.EditField_ChangeSampleWindow.Value;
                app.EditField_SampleWindow.Value = string(app.val);
            end

            datalineSloterBezPaketov = uiprogressdlg(app.UIFigure,'Title','Slotovanie','Icon','membrane.png', ...
                'Interpreter','html','Cancelable','on','CancelText','Cancel');
            datalineSloterBezPaketov.Message = 'Prebieha slotovanie vašich dát...';
            
            % na zaklade sloter_value vieme ci pocitame iba casy alebo pakety
            if all(app.what_casy_value == 1)||all(app.what_casy_value == 2)||all(app.what_casy_value == 3)
                sloter_value = 2;
            end
            
            app.d = app.val; %timeslot
            % ziskame si prirastky 'a' pomocou funkcie
            % sloter vdaka externemu suboru sloter.m a ulozime si ich
            % do tabulky sloter_values odkial ich potom presunieme do
            % jednotlivych pramennych app.a
            sloter_values = sloter(sloter_value,app.d,app.N,app.te,app.Timestamp);
            app.a = table2array(sloter_values(:,1));
            
            close(datalineSloterBezPaketov);
         
        end


        function Sigma_function(app,~)
            cisty_priemer_pre_sigmu = 0;
            % vypocet obycajneho priemeru
            for k = 1 : length(app.a)
                cisty_priemer_pre_sigmu(k) = mean(app.a(k));
            end

            % ulozime transponovane
            app.a=app.a';

            % ----------- || VYPOCET SIGMY || -------------------
            sigmaWindowValue = app.EditFieldDetail_Sigma.Value;
            %sigma vypocet = sqrt((pow2(a-mean))/(N-1));
            app.pocet = 0;
            app.pocet = length(cisty_priemer_pre_sigmu);

            % vypocet sigmy hornej a dolnej hranice
            %dh = app.priemer - 0.5 * sigma;
            %hh = app.priemer + 0.5 * sigma;

            app.hh = 0;
            app.dh = 0;
            for j=1:app.pocet
                app.sigma = sqrt(((app.a(j)-cisty_priemer_pre_sigmu(app.pocet))^2)/(app.pocet-1));
                app.hh(j) = cisty_priemer_pre_sigmu(j) + sigmaWindowValue * app.sigma;
                app.dh(j) = cisty_priemer_pre_sigmu(j) - sigmaWindowValue * app.sigma;
            end
            % --------------------------------------------------
        end

        function Prepocitaj(app,~)

            app.dataline = uiprogressdlg(app.UIFigure,'Title','Čakajte prosím','Icon','membrane.png',...
                'Interpreter','html','Message','Opening the application');
			app.dataline.Message = 'Začiatok prepočítavania...';
            pause(.5)
        
            % Perform calculations
            %------------------GUI LOADER 33 percent-----------------------
            app.dataline.Value = .33; 
            app.dataline.Message = 'Prebieha načítavanie vašich dát...';
            pause(3)            

            if app.what_pcap_value == 1 || app.what_pakety_value == 1 || app.what_pakety_value == 2 || app.what_pakety_value == 3
                app.Timestamp = importdata('TimestampSkuska.mat');
                app.PacketLength = importdata('PacketSkuska.mat');
            else
                app.Timestamp = importdata('TimestampSkuska.mat');
            end
            

            % Perform calculations
            %------------------GUI LOADER 67 percent-----------------------
            app.dataline.Value = .67;
            app.dataline.Message = 'Prebieha prepočítavanie...';
            pause(1)

            % T2 je vektor s nasimi casmi z PCAP suboru
            % v N je ulozena velkost PCAP suboru
            % v meanOfTimes je priemer casov
            % v stdOfTimes je standardna odchylka
            app.T2 = 0;
            app.T2 = app.Timestamp;
            app.N = length(app.T2);

            % counting delta from data
            % funkcia diff robi deltu medzi casmi
            app.te = 0;
            app.te = diff(app.T2);
            app.Sloter_function;            

            % Finish recalculations
            %------------------GUI FINISHER 100 percent--------------------
            app.dataline.Value = 1;
            app.dataline.Message = 'Koniec prepočítavania';
            pause(2)
        
            % Close dialog box
            close(app.dataline)

            app.x = app.T2(:,end);
            app.y = app.T2(:,end);
            app.ButtonZobrazHistogram_a.Text = "Zobraz histogram";
            app.EditField_Histogram_a.Value = 10;
            app.ButtonZobrazHistogram_b.Text = "Zobraz histogram";
            app.EditField_Histogram_b.Value = 10;
            if app.what_value == 1 && app.what_pcap_value == 1
                % VYKRESLENIE GRAFOV po prepocitani s novym oknom    
                % ak sme hodnoty nacitali z PCAP suboru zobrazime v
                % UIAxes_Kumulativ_Pakety velkosti paketov
                plot(app.UIAxes_Kumulativ_Pakety,app.PacketLength);
                plot(app.UIAxes_Te,app.te);
                app.Packet_figure = plot(app.UIAxes_KB,app.b);
                app.Time_figure = plot(app.UIAxes_Prirastky,app.a);    
            elseif app.what_value == 1 && app.what_casy_value == 1
                plot(app.UIAxes_Kumulativ_Pakety,app.T2);
                plot(app.UIAxes_Te,app.te);
                app.Time_figure = plot(app.UIAxes_Prirastky,app.a);    
            elseif app.what_value == 1 && app.what_pakety_value == 1
                % rovnako ak berieme ako svtup pakety v
                % UIAxes_Kumulativ_Pakety zobrazime velkosti paketov
                plot(app.UIAxes_Kumulativ_Pakety,app.PacketLength);
                plot(app.UIAxes_Te,app.te);
                app.Packet_figure = plot(app.UIAxes_KB,app.b);
                app.Time_figure = plot(app.UIAxes_Prirastky,app.a);     
            end
            app.okno = 0;

        end

        function Nacitaj_Tabulku(app,~)
            tab = app.UITable;
            if (app.what_casy_value == 1) || (app.what_casy_value == 2) || (app.what_casy_value == 3)
                % vyplnenie tabulky s casmi
                tab.Data = [app.Timestamp];
                tab.ColumnFormat = {'long'};
                tab.ColumnName = {'Časy'};
            elseif (app.what_pakety_value == 1) || (app.what_pakety_value == 2) || (app.what_pakety_value == 3)
                tab.Data = [app.Timestamp app.PacketLength];
                tab.ColumnFormat = {'long' 'long'};
                tab.ColumnName = {'Časy'; 'Pakety'};
            elseif app.what_pcap_value == 1
                % vyplnenie tabulky s casmi a velkostami paketov
                tab.Data = [app.Timestamp app.PacketLength];
                tab.ColumnFormat = {'long' 'long'};
                tab.ColumnName = {'Časy'; 'Pakety'};
            elseif app.what_pcap_value == 0
                % vyplnenie tabulky s velkostami paketov
                tab.Data = [app.PacketLength];
                tab.ColumnFormat = {'long'};
            end
            app.okno = 0;
        end

        function NacitajVstupnySubor(app)

            if app.what_casy_value == 1 || app.what_pakety_value == 1
                [app.filename, app.path]=uigetfile('*.xlsx');
            elseif app.what_casy_value == 2 || app.what_pakety_value == 2
                [app.filename, app.path]=uigetfile('*.csv');
            elseif app.what_casy_value == 3 || app.what_pakety_value == 3
                [app.filename, app.path]=uigetfile('*.mat');
            elseif app.what_pcap_value == 1
                [app.filename, app.path]=uigetfile('*.pcap');
            end

            %if app.filename ~= 0 || app.path ~= 0
            if all(app.filename ~= 0)&&all(app.path ~= 0)
                if (app.what_pcap_value == 1)
                    app.NacitajPCAP;
                else
                    app.NacitajCasy;
                end
            else
                message = {'Nebol načítaný žiadny súbor!'};
                uialert(app.UIFigure,message,'Warning',...
                'Icon','warning');
            end
        end

        function NacitajPCAP(app,~)

            app.SetNameOfInputFileWithData;

            app.dataline = uiprogressdlg(app.UIFigure,'Title','Čakajte prosím','Icon','membrane.png',...
                'Interpreter','html','Message','Opening the application');
            pause(.5)
        
            % Perform calculations
            %------------------GUI LOADER 33 percent-----------------------
            app.dataline.Value = .33; 
            app.dataline.Message = 'Prebieha načítavanie vašich dát z PCAP súboru...';
            pause(1)

            pcapValues = readPCAP(app.path,app.filename);
            Time = table2array(pcapValues(:,1));
            Packet = table2array(pcapValues(:,2));            
        
            % Perform calculations
            %------------------GUI LOADER 67 percent-----------------------
            app.dataline.Value = .67;
            app.dataline.Message = 'Spracovanie dát do objektov...';
            pause(1)
        
            % Finish calculations
            %------------------GUI FINISHER 100 percent--------------------
            app.dataline.Value = 1;
            app.dataline.Message = 'Koniec';
            pause(1)
        
            % Close dialog box
            close(app.dataline)

            %ulozime si nase vektory: cas a velkost paketov
            save('TimestampSkuska','Time');
            save('PacketSkuska',"Packet");
        
            %naimportujeme si casy a pakety pre rychlejsiu pracu
            %[TimestampFile, path]=uigetfile('*.mat');
            %Timestamp = importdata(fullfile(path, TimestampFile));
            %[PacketsFile, path]=uigetfile('*.mat');
            %PacketLength = importdata(fullfile(path, PacketsFile));
            app.Timestamp = importdata('TimestampSkuska.mat');
            app.PacketLength = importdata("PacketSkuska.mat");
    
            % T2 je vektor s nasimi casmi z PCAP suboru
            % v N je ulozena velkost PCAP suboru
            % v meanOfTimes je priemer casov
            % v stdOfTimes je standardna odchylka
            app.T2 = app.Timestamp;

            app.N = 0;
            app.meanOfTimes = 0;
            app.meanOfPackets = 0;
            app.stdOfTimes = 0;
            app.stdOfPackets = 0;

            app.N = length(app.T2);
            app.meanOfTimes = mean(app.T2);
            app.meanOfPackets = mean(app.PacketLength);
            app.stdOfTimes = std(app.T2);
            app.stdOfPackets = std(app.PacketLength);

            % counting delta from data
            % funkcia diff robi deltu medzi casmi
            app.te = diff(app.T2);
            medzery = app.te;
            save('Medzery','medzery');

            %Length of data
            app.NumberOfRows = app.N;
            %displaying the count of data
            app.EditField_LengthOfData.Value = string(app.NumberOfRows);
            app.EditField_MeanOfTimes.Value = string(app.meanOfTimes);
            app.EditField_MeanPackets.Value = string(app.meanOfPackets);
            app.EditField_StandardDeviation.Value = string(app.stdOfTimes);
            app.EditField_StandardDeviation_2.Value = string(app.stdOfPackets);

            % funkcia pre vypocet prirastkov podla velkosti okna z GUI
            app.Sloter_function;
        
            app.x = app.T2(:,end);
            app.y = app.T2(:,end);
            plot(app.UIAxes_Kumulativ_Pakety,app.PacketLength);
            app.UIAxes_Kumulativ_Pakety.Title.String = "Veľkosti paketov";
            plot(app.UIAxes_Te,app.te);
            app.Packet_figure = plot(app.UIAxes_KB,app.b);
            app.Time_figure = plot(app.UIAxes_Prirastky,app.a);

            app.Nacitaj_Tabulku;    
        end

        function NacitajCasy(app, ~)
            
            app.SetNameOfInputFileWithData;
            app.d = uiprogressdlg(app.UIFigure,'Title','Čakajte prosím','Icon','membrane.png',...
                'Interpreter','html','Message','Opening the application');
            pause(.5)
        
            % Perform calculations
            % ...
            app.d.Value = .33; 
            app.d.Message = 'Prebieha načítavanie vašich dát...';
            pause(1)

            if (app.what_pakety_value == 1 || app.what_pakety_value == 2 || app.what_pakety_value == 3)

                %naimportujeme si [casy a pakety v kB] z .mat, csv aj xlsx suboru
                app.Pakety = 0;
                % ak 1 alebo 2 potom vstupny subor je csv alebo xlsx
                if app.what_pakety_value == 1 || app.what_pakety_value == 2
                    app.Pakety = readtable(fullfile(app.path, app.filename));
                else
                    % ak 3 potom vstupny subor je .mat subor
                    app.Pakety = importdata(fullfile(app.path, app.filename));
                end
                
                % z nacitanych dat mame 2 stlpce a tie si rozdelime do premennych
                % Time ako stlpec casov
                Time = table2array(app.Pakety(:,1));
                % Packet ako stlpec paketov v kB
                Packet = table2array(app.Pakety(:,2));    
                % ulozime si nase vektory: cas a velkost paketov
                save('TimestampSkuska','Time');
                save('PacketSkuska',"Packet");

                % Perform calculations
                % ...
                app.d.Value = .67;
                app.d.Message = 'Dáta sa spracovávajú...';
                pause(1)

                %naimportujeme si casy a pakety pre rychlejsiu pracu
                app.Timestamp = importdata('TimestampSkuska.mat');
                app.PacketLength = importdata("PacketSkuska.mat");

                % T2 je vektor s nasimi casmi z mat suboru
                % v N je ulozena velkost mat suboru
                % v meanOfTimes je priemer casov
                % v stdOfTimes je standardna odchylka
                app.T2 = app.Timestamp;
    
                app.N = 0;
                app.meanOfTimes = 0;
                app.meanOfPackets = 0;
                app.stdOfTimes = 0;
    
                app.N = length(app.T2);
                app.meanOfTimes = mean(app.T2);
                app.meanOfPackets = mean(app.PacketLength);
                app.stdOfTimes = std(app.T2);
                app.stdOfPackets = std(app.PacketLength);
    
                % counting delta from data
                % funkcia diff robi deltu medzi casmi
                app.te = diff(app.T2);
    
                %Length of data
                app.NumberOfRows = app.N;
                %displaying the count of data
                app.EditField_LengthOfData.Value = string(app.NumberOfRows);
                app.EditField_MeanOfTimes.Value = string(app.meanOfTimes);
                app.EditField_MeanPackets.Value = string(app.meanOfPackets);
                app.EditField_StandardDeviation.Value = string(app.stdOfTimes);
                app.EditField_StandardDeviation_2.Value = string(app.stdOfPackets);
    
                % funkcia pre vypocet prirastkov podla velkosti okna z GUI
                app.Sloter_function;
            
                app.x = app.T2(:,end);
                app.y = app.T2(:,end);
                plot(app.UIAxes_Kumulativ_Pakety,app.PacketLength);
                app.UIAxes_Kumulativ_Pakety.Title.String = "Veľkosti paketov";
                plot(app.UIAxes_Te,app.te);
                app.Packet_figure = plot(app.UIAxes_KB,app.b);
                app.Time_figure = plot(app.UIAxes_Prirastky,app.a);  
        
            elseif (app.what_casy_value == 3)

                %naimportujeme si [casy] z .mat suboru
                app.Timestamp = 0;
                app.T2 = 0;
                app.Timestamp = importdata(fullfile(app.path, app.filename));
                Time = app.Timestamp;
                app.T2 = app.Timestamp;
                save('TimestampSkuska','Time');                
                app.Timestamp = importdata('TimestampSkuska.mat');

                % Perform calculations
                % ...
                app.d.Value = .67;
                app.d.Message = 'Dáta sa spracovávajú...';
                pause(1)

                app.N = 0;
                app.meanOfTimes = 0;
                app.stdOfTimes = 0;
    
                app.N = length(app.T2);
                app.meanOfTimes = mean(app.T2);
                app.stdOfTimes = std(app.T2);

                % counting delta from data
                % funkcia diff robi deltu medzi casmi/velkostami paketov
                app.te = diff(app.T2);
    
                %Length of data
                app.NumberOfRows = app.N;
                %displaying the count of data
                app.EditField_LengthOfData.Value = string(app.NumberOfRows);
                %app.EditField_MeanOfTimes.Value = string(app.meanOfTimes);
                %app.EditField_StandardDeviation.Value = string(app.stdOfTimes);
                app.SetMeanInfoBasedOnLoadedData;

                app.Sloter_bez_paketov_function;    

                % vykreslenie grafov pre kumulativ, medzery a 
                % prirastky casov/prirastky paketov lebo mame 
                % app.what_casy_value == 3 alebo app.what_pakety_value == 3
                plot(app.UIAxes_Kumulativ_Pakety,app.T2);
                app.UIAxes_Kumulativ_Pakety.Title.String = "Kumulatívne medzery medzi paketmi";
                plot(app.UIAxes_Te,app.te);
                app.Time_figure = plot(app.UIAxes_Prirastky,app.a);
                
            else
                %Nacitanie dat (casy) z csv/xlsx
                data = readtable(fullfile(app.path, app.filename));
                app.Timestamp = table2array(data);
                app.T2 = app.Timestamp;
                Time = app.Timestamp;
                save('TimestampSkuska','Time');
                app.Timestamp = 0;
                app.Timestamp = importdata('TimestampSkuska.mat');

                % Perform calculations
                % ...
                app.d.Value = .67;
                app.d.Message = 'Dáta sa spracovávajú...';
                pause(1)              
            
                % Finish calculations
                % ...
                app.d.Value = 1;
                app.d.Message = 'Koniec';
                pause(1)
            
                % Close dialog box
                close(app.d)
    
                app.VypisDetailyODatach;
    
                app.Sloter_bez_paketov_function;
    
                app.x = app.T2(:,end);
                app.y = app.T2(:,end);    
                plot(app.UIAxes_Kumulativ_Pakety,app.T2);
                app.UIAxes_Kumulativ_Pakety.Title.String = "Kumulatívne medzery medzi paketmi";
                plot(app.UIAxes_Te,app.te);
                app.Time_figure = plot(app.UIAxes_Prirastky,app.a);
                app.okno = 0;
            end
            app.Nacitaj_Tabulku;
        end


        function VypisDetailyODatach(app, ~)

            app.N = 0;
            app.meanOfTimes = 0;
            app.stdOfTimes = 0;

            app.N = length(app.T2);
            %mean of times
            app.meanOfTimes = mean(app.T2);
            %std of times
            app.stdOfTimes = std(app.T2);

            %counting delta from data
            app.te = diff(app.T2);

            %Length of data
            app.NumberOfRows = 0;
            app.NumberOfRows = app.N;
            %displaying the count of data
            app.EditField_LengthOfData.Value = string(app.NumberOfRows);
            app.SetMeanInfoBasedOnLoadedData;
        end

        function SetNameOfInputFileWithData(app)         
            % app.vstupneData = nazov suboru vlozenych dat
            app.vstupneData = convertCharsToStrings(app.filename);
            app.EditField_NacitanySubor.Value = string(app.vstupneData);            
        end

        function SetMeanInfoBasedOnLoadedData(app)
            if app.what_casy_value ~= 0
                app.EditField_MeanOfTimes.Value = string(app.meanOfTimes);
                app.EditField_StandardDeviation.Value = string(app.stdOfTimes);
            elseif app.what_pakety_value ~= 0
                app.EditField_MeanPackets.Value = string(app.meanOfTimes);
                app.EditField_StandardDeviation_2.Value = string(app.stdOfTimes);
            end
            
        end

        function NacitajVstupneData(app)

            [app.filenameFunkcie, app.pathFunkcie]=uigetfile('*.mat');   

            if all(app.filenameFunkcie ~= 0)&&all(app.pathFunkcie ~= 0)
                app.pathFunkcie = fullfile(app.pathFunkcie, app.filenameFunkcie);
                app.vstupneDataPreParameter = importdata(app.pathFunkcie);
                nacitany3DVstup = convertCharsToStrings(app.pathFunkcie);
                if app.typMetody == 1
                    app.Label2DNacitanySubor.Text = string(nacitany3DVstup); % pre 2D metody
                elseif app.typMetody == 2
                    app.LabelNacitanySubor.Text = string(nacitany3DVstup); % pre 3D metody
                elseif app.typMetody == 3
                    app.LabelDetail_NacitanySubor.Text = string(nacitany3DVstup); % pre Detaielne zobrazenie prirastkov
                end                
            else
                message = {'Nebol načítaný žiadny súbor!'};
                uialert(app.UIFigure,message,'Warning',...
                'Icon','warning');
            end
        end

        function Resetovat3DOkno(app)
            app.func_param = 0;
            app.metoda1_2 = 0;
            cla(app.UIAxes3DFunkcia);
            cla(app.UIAxes3DViewFunkcia);
            app.EditField_Hodnota1.Value = 512;
            app.EditField_Hodnota2.Value = 1024;
            app.EditField_Hodnota3.Value = 2048;
            app.EditField_Hodnota4.Value = 3000;
            app.EditField_Hodnota5.Value = 3500;
            app.LabelNacitanySubor.Text = 'žiadny';
            app.pathFunkcie = 0;
            app.filenameFunkcie = 0;
            app.vstupneDataPreParameter = 0;
            app.UIAxes3DFunkcia.Title.String = 'Title';
            app.UIAxes3DViewFunkcia.Title.String = 'Title';
            app.EditFieldVynasob3D.Value = 2;
        end

        function Resetovat2DOkno(app)
            app.func_param = 0;
            app.vstupneDataPreParameter = 0;
            app.metoda1_2 = 0;
            cla(app.UIAxes2DFunkcia);
            cla(app.UIAxes2DFunkciaView);
            app.EditField2D_Hodnota.Value = 512;
            app.Label2DNacitanySubor.Text = 'žiadny'; 
            app.UIAxes2DFunkcia.Title.String = 'Title';
            app.UIAxes2DFunkciaView.Title.String = 'Title';    
            app.EditFieldVynasob2D.Value = 2;
        end

        function StiahniFigure(app, vstup, graf) 
            % skontrolujeme, ci je nieco na vstupe
            if ~isempty(vstup)
                ux = graf;
                fig1 = figure;
                ax = axes;
                copyobj(ux.Children,ax);
                [file_for_uiaxes, path_for_uiaxes] = uiputfile('*.fig');

                if all(file_for_uiaxes ~= 0)||all(path_for_uiaxes ~= 0)
                    savefig( fig1, fullfile(path_for_uiaxes,file_for_uiaxes));
                else
                    message = {'Nevybrali ste priečinok pre uloženie prírastkov...'};
                    uialert(app.UIFigure,message,'Warning',...
                    'Icon','warning');
                end
            % ak nie su data na stiahnutie zobrazime error banner
            else
                message = {'Nie sú dostupné žiadne dáta pre stiahnutie...'};
                uialert(app.UIFigure,message,'Warning',...
                'Icon','warning');
            end
        end

        % funkcia sluzi na stiahnutie vstupnych dat alebo uz naslotovanych
        % data v podobe prirastkov
        function StiahniData(app, vstup, typ_dat, warning_message, identifikator)

            if vstup ~= 0

                data_na_stiahnutie = vstup;
                % uigetdir nam umozni si vybrat folder pre ulozenie nasich dat
                subor_pre_ulozenie = uigetdir;
                if subor_pre_ulozenie ~= 0
	                path_as_string = convertCharsToStrings(subor_pre_ulozenie);
	                tempName = app.vstupneData;
	                tempOkno = string(app.d);
                    % ak je ident. 1 potom ma nazov stiahnuteho suboru aj
                    % informaciu o velkosti pouziteho vypoctoveho okna
                    if identifikator == 1
                        path_as_string = path_as_string + typ_dat + tempName + "_sOknom_" + tempOkno + ".mat";
                    elseif identifikator == 0
                        path_as_string = path_as_string + typ_dat + tempName + ".mat";
                    else
                        path_as_string = path_as_string + typ_dat + app.filenameFunkcie + '_' + app.UIAxes2DFunkcia.Title.String + ".mat";
                    end
                    save(path_as_string,'data_na_stiahnutie');	                          
                else
	                message = {warning_message};
	                uialert(app.UIFigure,message,'Warning',...
	                'Icon','warning');
                end 

            else
                message = {'Nie sú k dispozícií dáta na stiahnuntie...'};
                uialert(app.UIFigure,message,'Warning',...
                'Icon','warning');
            end

        end

        function NastavMenu3D(app, func_param_hodnota, metoda1_2_hodnota, Title_nazov, Title2_nazov)
            app.Resetovat3DOkno;
            app.Panel_3DFunkcie.Visible = "on";
            app.func_param = func_param_hodnota;
            app.metoda1_2 = metoda1_2_hodnota;
            app.UIAxes3DFunkcia.Title.String = Title_nazov;
            app.UIAxes3DViewFunkcia.Title.String = Title2_nazov;
        end

        function NastavMenu2D(app, func_param_hodnota, metoda1_2_hodnota, Title_nazov, Title2_nazov)
            app.Resetovat2DOkno;
            app.Panel_2DFunkcie.Visible = "on";
            app.func_param = func_param_hodnota;
            app.metoda1_2 = metoda1_2_hodnota;
            app.UIAxes2DFunkcia.Title.String = Title_nazov;
            app.UIAxes2DFunkciaView.Title.String = Title2_nazov;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: PCAPsuborMenu, UIAxes_Kumulativ_Pakety
        function PCAPfileMenuSelected(app, event)
            app.what_value = 1;
            app.what_pcap_value = 1;
            app.what_casy_value = 0;
            app.what_pakety_value = 0;
            app.NacitajVstupnySubor;
            
        end

        % Button pushed function: PrepocitajButton
        function PrepocitajButtonPushed(app, event)

            app.what_value = 1;             % toto nam urci ze sme zmackli prepocitaj button na hlavnej casti
            if app.what_pcap_value == 1
                app.what_pcap_value = 1;
                app.what_casy_value = 0;
                app.what_pakety_value = 0;
                app.what_value = 1;
            elseif  app.what_casy_value == 1 || app.what_casy_value == 2 || app.what_casy_value == 3
                app.what_pcap_value = 0;
                app.what_casy_value = 1;
                app.what_pakety_value = 0;
                app.what_value = 1;
            elseif app.what_pakety_value == 1 || app.what_pakety_value == 2 || app.what_pakety_value == 3
                app.what_pakety_value = 1;
                app.what_casy_value = 0;
                app.what_pcap_value = 0;
                app.what_value = 1;
            end
            app.Prepocitaj;            

        end

        % Menu selected function: DetaielnejsiVystupMenu
        function DetaielnejsiVystupMenuMenuSelected(app, event)
            app.Panel_DetaielnejsiVystup.Visible = 'on';
        end

        % Menu selected function: XLSX_suborMenu
        function XLSX_suborMenuMenuSelected(app, event)
            app.what_casy_value = 1;
            app.what_value = 1;
            app.what_pcap_value = 0;
            app.what_pakety_value = 0;
            app.NacitajVstupnySubor;
        end

        % Menu selected function: CSV_suborMenu
        function CSV_suborMenuSelected(app, event)
            app.what_casy_value = 2;
            app.what_value = 1;
            app.what_pcap_value = 0;
            app.what_pakety_value = 0;
            app.NacitajVstupnySubor;
        end

        % Menu selected function: MAT_suborMenu
        function MAT_suborMenuSelected(app, event)
            app.what_casy_value = 3;
            app.what_value = 1;
            app.what_pcap_value = 0;
            app.what_pakety_value = 0;
            app.NacitajVstupnySubor;
        end

        % Menu selected function: UpravaVstupuMenu
        function UpravaVstupuMenuMenuSelected(app, event)
            app.Panel_UpravaVstupu.Visible = "on";            
        end

        % Button pushed function: UkoniButton
        function UkoniButtonPushed(app, event)
            app.Panel_UpravaVstupu.Visible = "off";
            cla(app.Panel_UIAxes);
            app.Panel_StrihajOd.Value = 1;
            app.Panel_StrihajPo.Value = 100000;
            app.vystrihnuteData = 0;
        end

        % Button pushed function: UlozkBPrirastkyButton
        function UlozkBPrirastkyButtonPushed(app, event)
           app.StiahniData(app.b, "\prirastky_kB_", 'Nevybrali ste priečinok pre uloženie prírastkov paketov v kB...',1);
        end

        % Button pushed function: UlozPrirastkyCasuButton
        function UlozPrirastkyCasuButtonPushed(app, event)
            app.StiahniData(app.a,"\prirastky_casu_",'Nevybrali ste priečinok pre uloženie prírastkov času...',1);            
        end

        % Button pushed function: NacitatVstupButton
        function NacitatVstupButtonPushed(app, event)
            
            [app.filename, app.path]=uigetfile('*.mat');
            temp_path = fullfile(app.path, app.filename);
            if ( length(temp_path) > 3) 
                app.importovaneData = importdata(temp_path);
                plot(app.Panel_UIAxes,app.importovaneData); 
                app.Panel_StrihajPo.Value = double(length(app.importovaneData));
            else
                message = {'Súbor sa nenašiel...'};
                uialert(app.UIFigure,message,'Warning',...
                'Icon','warning');
            end

        end

        % Button pushed function: VystrihniButton
        function VystrihniButtonPushed(app, event)
           
            app.scaleFrom = app.Panel_StrihajOd.Value;
            app.scaleTo = app.Panel_StrihajPo.Value;
            app.vystrihnuteData = app.importovaneData(app.scaleFrom:app.scaleTo);
           
            % ideme si ulozit tie nove vystrihnute data
            vystrihnute_prirastky = app.vystrihnuteData;
            % uigetdir nam umozni si vybrat folder pre ulozenie
            priecinok = uigetdir;
            path_as_string = convertCharsToStrings(priecinok);
            tempName = convertCharsToStrings(app.filename);
            path_as_string = path_as_string + "\" + "vystrihnute_prirastky_" + tempName + ".mat";
            save(path_as_string,'vystrihnute_prirastky'); 
           

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
            cla(app.UIAxes_KB);
            cla(app.UIAxes_Prirastky);
            cla(app.UIAxes_Te);
            cla(app.UIAxes_Kumulativ_Pakety);
            app.UIAxes_Kumulativ_Pakety.Title.String = "Kumulatívne medzery medzi paketmi";
            %app.HurstEditField_1.Value = '0';

            app.EditField_NacitanySubor.Value = 'žiadny';
            app.EditField_LengthOfData.Value = '0';
            app.EditField_MeanOfTimes.Value = '0';
            app.EditField_MeanPackets.Value = '0';
            app.EditField_StandardDeviation.Value = '0';
            app.EditField_StandardDeviation_2.Value = '0';
            app.EditField_SampleWindow.Value = '3000';
            app.EditField_ChangeSampleWindow.Value = 1;
            app.ButtonZobrazHistogram_a.Text = "Zobraz histogram";
            app.EditField_Histogram_a.Value = 10;
            app.ButtonZobrazHistogram_b.Text = "Zobraz histogram";
            app.EditField_Histogram_b.Value = 10;

            tab = app.UITable;
            tab.Data = [];

        end

        % Button pushed function: UlozPacketLengthButton
        function UlozPacketLengthButtonPushed(app, event)
            % pri ulozeni velkosti paketov spolu ukladame aj stlpec casov
            casy = app.Timestamp;
            pakety = app.PacketLength;
            t = table();
            t = addvars(t,casy);
            t = addvars(t,pakety);
            
            app.StiahniData(t,"\velkosti_paketov_",'Nevybrali ste priečinok pre uloženie veľkosti paketov...',0);          
        end

        % Button pushed function: UlozTimestampButton
        function UlozTimestampButtonPushed(app, event)
            app.StiahniData(app.Timestamp,"\prichody_casov_",'Nevybrali ste priečinok pre uloženie časov...',0);
        end

        % Menu selected function: XLSX_paketyMenu
        function XLSX_paketyMenuSelected(app, event)
            app.what_pakety_value = 1;
            app.what_value = 1;
            app.what_pcap_value = 0;
            app.what_casy_value = 0;
            app.NacitajVstupnySubor;
            
        end

        % Menu selected function: CSV_paketyMenu
        function CSV_paketyMenuSelected(app, event)
            app.what_pakety_value = 2;
            app.what_value = 1;
            app.what_pcap_value = 0;
            app.what_casy_value = 0;
            app.NacitajVstupnySubor;
        end

        % Menu selected function: MAT_paketyMenu
        function MAT_paketyMenuSelected(app, event)
            app.what_pakety_value = 3;
            app.what_value = 1;
            app.what_pcap_value = 0;
            app.what_casy_value = 0;
            app.NacitajVstupnySubor;
        end

        % Button pushed function: Button3DFunkcie_Ukoncit
        function Button3DFunkcie_UkoncitPushed(app, event)
            app.Panel_3DFunkcie.Visible = "off";
            app.UIAxes3DFunkcia.Title.String = '';
            app.UIAxes3DViewFunkcia.Title.String = '';
            app.Resetovat3DOkno;
        end

        % Button pushed function: ButtonNacitatVstup3D
        function ButtonNacitatVstup3DPushed(app, event)
            app.typMetody = 2;
            app.NacitajVstupneData;
        end

        % Button pushed function: ButtonVypocitat3D
        function ButtonVypocitat3DPushed(app, event)
            
            Nt_3D = app.vstupneDataPreParameter;
            va1 = app.EditField_Hodnota1.Value;
            va2 = app.EditField_Hodnota2.Value;
            va3 = app.EditField_Hodnota3.Value;
            va4 = app.EditField_Hodnota4.Value;
            va5 = app.EditField_Hodnota5.Value;
      
            datalineComputing3D = uiprogressdlg(app.UIFigure,'Title','Prebieha výpočet',...
            'Indeterminate','on','Icon','membrane.png','Interpreter','html');

            % v T_values su vypocitane hodnoty pre vybranu metodu
            % pocita sa to vdaka metode v m subore = je to rychlejsie
            app.param3D_v = createTable(Nt_3D,va1,va2,va3,va4,va5,app.func_param,app.metoda1_2);
            
            close(datalineComputing3D);
            N_3D = length(Nt_3D);

            ax = app.UIAxes3DFunkcia;
            ax.Title.FontWeight = 'normal';    
            ax.View = [1 3];
            t_3D  = linspace(1,N_3D,N_3D); 
            for t_val=2:width(app.param3D_v)
                TV = table2array(app.param3D_v(:,t_val));
                TV = [zeros(1,N_3D-length(TV)),TV];
                tw = t_val*ones(1,N_3D);       
                
                % pre priemer a odchylku vykreslime naraz aj data
%                 if app.func_param == 1 || app.func_param == 2 || app.func_param == 4 || app.func_param == 8
%                     plot3(ax,t_3D,tw,Nt_3D,'-'); 
%                     hold(ax,"on")
%                     plot3(ax,t_3D,tw,TV,'-',Color='r', LineWidth=2);
%                     hold(ax,"on")
%                 else
                    plot3(ax,t_3D,tw,TV,'-', LineWidth=2);
                    hold(ax,"on")
%                 end
                grid(ax,"on")
            end

            axView = app.UIAxes3DViewFunkcia;
            tcw1 = linspace(512,N_3D,N_3D-512);
            tcw2 = linspace(512+1,N_3D,N_3D-(512+1));
            data = table2array(app.param3D_v(:,1));
            if app.metoda1_2 == 1
                plot(axView,t_3D,Nt_3D,tcw1,data,'r');
            elseif app.metoda1_2 == 2
                plot(axView,t_3D,Nt_3D,tcw2,data,'r');
            end


        end

        % Menu selected function: Menu3DPriemer
        function Menu3DPriemerSelected(app, event)
            app.NastavMenu3D(1,1,'Priemer','Náhľad dát a priemeru s výpočtovým oknom 512');
        end

        % Menu selected function: Menu3DOdchylka
        function Menu3DOdchylkaSelected(app, event)
            app.NastavMenu3D(2,1,'Smerodajná odchýlka','Náhľad dát a odchýlky s výpočtovým oknom 512');
        end

        % Menu selected function: Menu3DVariabilnost
        function Menu3DVariabilnostSelected(app, event)
            app.NastavMenu3D(3,1,'Koeficient variabilnosti','Náhľad dát a variabilnosti s výpočtovým oknom 512');
        end

        % Menu selected function: Menu3DSikmost
        function Menu3DSikmostSelected(app, event)
            app.NastavMenu3D(4,1,'Šikmosť','Náhľad dát a šikmosti s výpočtovým oknom 512');
        end

        % Menu selected function: Menu3DSpicatost
        function Menu3DSpicatostSelected(app, event)
            app.NastavMenu3D(5,1,'Špičatosť','Náhľad dát a špičatosti s výpočtovým oknom 512');
        end

        % Menu selected function: Menu3DHurst
        function Menu3DHurstSelected(app, event)
            app.NastavMenu3D(6,1,'Hurstov koeficient','Náhľad dát a hursta s výpočtovým oknom 512');
        end

        % Menu selected function: Menu3DEntropia
        function Menu3DEntropiaSelected(app, event)
            app.NastavMenu3D(7,1,'Entropia','Náhľad dát a entropie s výpočtovým oknom 512');
        end

        % Menu selected function: Menu3DEnergia
        function Menu3DEnergiaSelected(app, event)
            app.NastavMenu3D(8,1,'Energia','Náhľad dát a energie s výpočtovým oknom 512');
        end

        % Menu selected function: Menu3DAutoregresia
        function Menu3DAutoregresiaSelected(app, event)
            app.NastavMenu3D(9,2,'Autoregresný koeficient','Náhľad dát a autoregresie s výpočtovým oknom 512');
        end

        % Menu selected function: Menu3DKorelacia
        function Menu3DKorelaciaSelected(app, event)
            app.NastavMenu3D(10,2,'Korelácia','Náhľad dát a korelácie s výpočtovým oknom 512');
        end

        % Menu selected function: Menu3DDivergencia
        function Menu3DDivergenciaSelected(app, event)
            app.NastavMenu3D(11,2,'Divergencia','Náhľad dát a divergencie s výpočtovým oknom 512');
        end

        % Menu selected function: Menu2DPriemer
        function Menu2DPriemerSelected(app, event)
            app.NastavMenu2D(1,1,'Priemer','Vstupné dáta s vypočítaným priemerom');
        end

        % Button pushed function: ButtonVypocitat2D
        function ButtonVypocitat2DPushed(app, event)
        
            Nt_2D = app.vstupneDataPreParameter;
            N_2D = length(Nt_2D);
            t_2D = linspace(1,N_2D,N_2D);
            okno_2D = app.EditField2D_Hodnota.Value;
            
            datalineComputing2D = uiprogressdlg(app.UIFigure,'Title','Prebieha výpočet',...
            'Indeterminate','on','Icon','membrane.png','Interpreter','html');

            app.param_v = calculate2D(Nt_2D,okno_2D,app.func_param,app.metoda1_2);

            close(datalineComputing2D);
                          
            axView = app.UIAxes2DFunkciaView;
            tcw1 = linspace(okno_2D,N_2D,N_2D-okno_2D);  
            tcw2 = linspace(okno_2D+1,N_2D,N_2D-(okno_2D+1));
            if app.metoda1_2 == 1
                plot(app.UIAxes2DFunkcia,tcw1,app.param_v,Color='r');
                plot(axView,t_2D,Nt_2D,tcw1,app.param_v,'r');
            else
                plot(app.UIAxes2DFunkcia,tcw2,app.param_v,Color='r');
                plot(axView,t_2D,Nt_2D,tcw2,app.param_v,'r');
            end

        end

        % Button pushed function: ButtonNacitatVstup2D
        function ButtonNacitatVstup2DPushed(app, event)
            app.typMetody = 1;
            app.NacitajVstupneData;
        end

        % Button pushed function: Button2DFunkcie_Ukoncit
        function Button2DFunkcie_UkoncitPushed(app, event)
            app.Panel_2DFunkcie.Visible = 'off';
            app.Resetovat2DOkno;
        end

        % Menu selected function: Menu2DOdchylka
        function Menu2DOdchylkaSelected(app, event)
            app.NastavMenu2D(2,1,'Odchýlka','Vstupné dáta s vypočítanou odchýlkou');
        end

        % Menu selected function: Menu2DVariabilnost
        function Menu2DVariabilnostSelected(app, event)
            app.NastavMenu2D(3,1,'Variabilnosť','Vstupné dáta s vypočítanou variabilnosťou');
        end

        % Menu selected function: Menu2DSikmost
        function Menu2DSikmostSelected(app, event)
            app.NastavMenu2D(4,1,'Šikmosť','Vstupné dáta s vypočítanou šikmosťou');
        end

        % Menu selected function: Menu2DSpicatost
        function Menu2DSpicatostSelected(app, event)
            app.NastavMenu2D(5,1,'Špičatosť','Vstupné dáta s vypočítanou špičatosťou');
        end

        % Menu selected function: Menu2DHurst
        function Menu2DHurstSelected(app, event)
            app.NastavMenu2D(6,1,'Hurst','Vstupné dáta s vypočítaným Hurstom');
        end

        % Menu selected function: Menu2DEntropia
        function Menu2DEntropiaSelected(app, event)
            app.NastavMenu2D(7,1,'Entropia','Vstupné dáta s vypočítanou entropiou');
        end

        % Menu selected function: Menu2DEnergia
        function Menu2DEnergiaSelected(app, event)
            app.NastavMenu2D(8,1,'Energia','Vstupné dáta s vypočítanou energiou');
        end

        % Menu selected function: Menu2DAutoregresia
        function Menu2DAutoregresiaSelected(app, event)
            app.NastavMenu2D(9,2,'Autoregresia','Vstupné dáta s vypočítanou autoregresiou');
        end

        % Menu selected function: Menu2DKorelacia
        function Menu2DKorelaciaSelected(app, event)
            app.NastavMenu2D(10,2,'Korelácia','Vstupné dáta s vypočítanou koreláciou');
        end

        % Menu selected function: Menu2DDivergencia
        function Menu2DDivergenciaSelected(app, event)
            app.NastavMenu2D(11,2,'Divergencia','Vstupné dáta s vypočítanou divergenciou');
        end

        % Button pushed function: ButtonDetail_Ukoncit
        function ButtonDetail_UkoncitPushed(app, event)
            app.Panel_DetaielnejsiVystup.Visible = "off";
            app.ButtonDetail_ResetovatPushed;
        end

        % Button pushed function: ButtonDetail_NacitatVstup
        function ButtonDetail_NacitatVstupPushed(app, event)
            app.typMetody = 3;
            app.NacitajVstupneData;
        end

        % Button pushed function: ButtonDetail_Vypocitat
        function ButtonDetail_VypocitatPushed(app, event)
            
            app.what_value = 2;
            app.a = app.vstupneDataPreParameter;

            % standardDeviation = posuvna funkcia so štandardnou odchýlkou
            % priemer = funkcia s posúvnym priemerom
            mWindowValue = app.EditFieldDetail_Priemer.Value;
            stWindowValue = app.EditFieldDetail_Odchylka.Value;
            app.standardDeviation = movstd(app.a,[mWindowValue 0],'Endpoints','fill');
            app.priemer = movmean(app.a,[stWindowValue 0],'Endpoints','fill');
            app.Sigma_function;        

            plot(app.UIAxesDetail,app.a);

        end

        % Button pushed function: ButtonDetail_Resetovat
        function ButtonDetail_ResetovatPushed(app, event)
            cla(app.UIAxesDetail);
            app.LabelDetail_NacitanySubor.Text = 'žiadny';
            app.EditFieldDetail_Odchylka.Value = 1;
            app.EditFieldDetail_Priemer.Value = 1;
            app.EditFieldDetail_Sigma.Value = 1;
            app.CheckBoxDetail_Priemer.Value = false;
            app.CheckBoxDetail_Odchylka.Value = false;
            app.CheckBoxDetail_DH.Value = false;
            app.CheckBoxDetail_HH.Value = false;
            app.vstupneDataPreParameter = 0;
            app.a = 0;
            app.dh = 0;
            app.hh = 0;
            app.standardDeviation = 0;
            app.priemer = 0;

        end

        % Value changed function: CheckBoxDetail_DH
        function CheckBoxDetail_DHValueChanged(app, event)
            valDH = app.CheckBoxDetail_DH.Value;
            if valDH == 1
                plot(app.UIAxesDetail,app.dh, Color='magenta');
            else
                plot(app.UIAxesDetail,app.dh, Color='white');
            end            
        end

        % Value changed function: CheckBoxDetail_HH
        function CheckBoxDetail_HHValueChanged(app, event)
            valHH = app.CheckBoxDetail_HH.Value;
            if valHH == 1
                plot(app.UIAxesDetail,app.hh, Color='cyan');
            else
                plot(app.UIAxesDetail,app.hh, Color='white');
            end            
        end

        % Value changed function: CheckBoxDetail_Odchylka
        function CheckBoxDetail_OdchylkaValueChanged(app, event)
            valstd = app.CheckBoxDetail_Odchylka.Value;
            if valstd == 1
                plot(app.UIAxesDetail,app.standardDeviation, Color='green');
            else
                plot(app.UIAxesDetail,app.standardDeviation, Color='white');
            end               
        end

        % Value changed function: CheckBoxDetail_Priemer
        function CheckBoxDetail_PriemerValueChanged(app, event)
            valm = app.CheckBoxDetail_Priemer.Value;
            if valm == 1
                plot(app.UIAxesDetail,app.priemer, Color='red');
            else
                plot(app.UIAxesDetail,app.priemer, Color='white');
            end 
        end

        % Button pushed function: ButtonDetail_ClearGraf
        function ButtonDetail_ClearGrafPushed(app, event)
            cla(app.UIAxesDetail);
        end

        % Button pushed function: ButtonUlozFigure_b
        function ButtonUlozFigure_bPushed(app, event)
            app.StiahniFigure(app.b, app.UIAxes_KB);
        end

        % Button pushed function: ButtonUlozFigure_a
        function ButtonUlozFigure_aPushed(app, event)
            app.StiahniFigure(app.a, app.UIAxes_Prirastky);
        end

        % Button pushed function: ButtonVynasobParameter2D
        function ButtonVynasobParameter2DPushed(app, event)
            
            hodnota_nasobenie = app.EditFieldVynasob2D.Value;
            Nt_2D = app.vstupneDataPreParameter;
            N_2D = length(Nt_2D);
            t_2D = linspace(1,N_2D,N_2D);
            okno_2D = app.EditField2D_Hodnota.Value;
            
            axView = app.UIAxes2DFunkciaView;
            tcw1 = linspace(okno_2D,N_2D,N_2D-okno_2D);  
            tcw2 = linspace(okno_2D+1,N_2D,N_2D-(okno_2D+1));
            if app.metoda1_2 == 1
                plot(axView,t_2D,Nt_2D,tcw1,app.param_v*hodnota_nasobenie,'r');
            else
                plot(axView,t_2D,Nt_2D,tcw2,app.param_v*hodnota_nasobenie,'r');
            end
        end

        % Button pushed function: ButtonUlozParameter2D
        function ButtonUlozParameter2DPushed(app, event)
            app.StiahniData(app.param_v,'\parameter_','Nevybrali ste priečinok pre uloženie parametra...',2);
        end

        % Button pushed function: ButtonVynasobParameter3D
        function ButtonVynasobParameter3DPushed(app, event)
            
            hodnota_nasobenie = app.EditFieldVynasob3D.Value;
            
            Nt_3D = app.vstupneDataPreParameter;
            N_3D = length(Nt_3D);
            t_3D  = linspace(1,N_3D,N_3D); 

            axView = app.UIAxes3DViewFunkcia;
            tcw1 = linspace(512,N_3D,N_3D-512);
            tcw2 = linspace(512+1,N_3D,N_3D-(512+1));
            data = table2array(app.param3D_v(:,1));
            if app.metoda1_2 == 1
                plot(axView,t_3D,Nt_3D,tcw1,data*hodnota_nasobenie,'r');
            elseif app.metoda1_2 == 2
                plot(axView,t_3D,Nt_3D,tcw2,data*hodnota_nasobenie,'r');
            end
        end

        % Button pushed function: ButtonZobrazHistogram_a
        function ButtonZobrazHistogram_aPushed(app, event)
            button_text_histogram = "Zobraz histogram";
            button_text_prirastky = "Zobraz prírastky";
            if app.ButtonZobrazHistogram_a.Text == button_text_histogram
                cla(app.UIAxes_Prirastky);
                pocet_tried = app.EditField_Histogram_a.Value;
                histogram(app.UIAxes_Prirastky,app.a,pocet_tried);
                app.ButtonZobrazHistogram_a.Text = button_text_prirastky;
            elseif app.ButtonZobrazHistogram_a.Text == button_text_prirastky
                cla(app.UIAxes_Prirastky);
                plot(app.UIAxes_Prirastky,app.a);
                app.ButtonZobrazHistogram_a.Text = button_text_histogram;
            end

        end

        % Button pushed function: ButtonZobrazHistogram_b
        function ButtonZobrazHistogram_bPushed(app, event)
            button_text_histogram = "Zobraz histogram";
            button_text_prirastky = "Zobraz prírastky";
            if app.ButtonZobrazHistogram_b.Text == button_text_histogram
                cla(app.UIAxes_KB);
                pocet_tried = app.EditField_Histogram_b.Value;
                histogram(app.UIAxes_KB,app.b,pocet_tried);
                app.ButtonZobrazHistogram_b.Text = button_text_prirastky;
            elseif app.ButtonZobrazHistogram_b.Text == button_text_prirastky
                cla(app.UIAxes_KB);
                plot(app.UIAxes_KB,app.b);
                app.ButtonZobrazHistogram_b.Text = button_text_histogram;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VstupyMenu = uimenu(app.UIFigure);
            app.VstupyMenu.Text = 'Vstupy';
            app.CasyMenu = uimenu(app.VstupyMenu);
            app.CasyMenu.Text = 'Čas z';
            app.XLSX_suborMenu = uimenu(app.CasyMenu);
            app.XLSX_suborMenu.MenuSelectedFcn = createCallbackFcn(app, @XLSX_suborMenuMenuSelected, true);
            app.XLSX_suborMenu.Text = '.xlsx subor';
            app.CSV_suborMenu = uimenu(app.CasyMenu);
            app.CSV_suborMenu.MenuSelectedFcn = createCallbackFcn(app, @CSV_suborMenuSelected, true);
            app.CSV_suborMenu.Text = '.csv subor';
            app.MAT_suborMenu = uimenu(app.CasyMenu);
            app.MAT_suborMenu.MenuSelectedFcn = createCallbackFcn(app, @MAT_suborMenuSelected, true);
            app.MAT_suborMenu.Text = '.mat subor';
            app.PaketyzMenu = uimenu(app.VstupyMenu);
            app.PaketyzMenu.Text = 'Pakety z';
            app.XLSX_paketyMenu = uimenu(app.PaketyzMenu);
            app.XLSX_paketyMenu.MenuSelectedFcn = createCallbackFcn(app, @XLSX_paketyMenuSelected, true);
            app.XLSX_paketyMenu.Text = '.xlsx subor';
            app.CSV_paketyMenu = uimenu(app.PaketyzMenu);
            app.CSV_paketyMenu.MenuSelectedFcn = createCallbackFcn(app, @CSV_paketyMenuSelected, true);
            app.CSV_paketyMenu.Text = '.csv subor';
            app.MAT_paketyMenu = uimenu(app.PaketyzMenu);
            app.MAT_paketyMenu.MenuSelectedFcn = createCallbackFcn(app, @MAT_paketyMenuSelected, true);
            app.MAT_paketyMenu.Text = '.mat subor';
            app.PCAPsuborMenu = uimenu(app.VstupyMenu);
            app.PCAPsuborMenu.MenuSelectedFcn = createCallbackFcn(app, @PCAPfileMenuSelected, true);
            app.PCAPsuborMenu.Text = 'PCAP súbor';
            app.FunkcionalityMenu = uimenu(app.UIFigure);
            app.FunkcionalityMenu.Text = 'Funkcionality';
            app.DetaielnejsiVystupMenu = uimenu(app.FunkcionalityMenu);
            app.DetaielnejsiVystupMenu.MenuSelectedFcn = createCallbackFcn(app, @DetaielnejsiVystupMenuMenuSelected, true);
            app.DetaielnejsiVystupMenu.Text = 'Detailnejší výstup prírastkov';
            app.UpravaVstupuMenu = uimenu(app.FunkcionalityMenu);
            app.UpravaVstupuMenu.MenuSelectedFcn = createCallbackFcn(app, @UpravaVstupuMenuMenuSelected, true);
            app.UpravaVstupuMenu.Text = 'Upravenie vstupu pre IMPORT';
            app.MenuFunkcie2D = uimenu(app.UIFigure);
            app.MenuFunkcie2D.Text = 'Funkcie';
            app.Menu2DPriemer = uimenu(app.MenuFunkcie2D);
            app.Menu2DPriemer.MenuSelectedFcn = createCallbackFcn(app, @Menu2DPriemerSelected, true);
            app.Menu2DPriemer.Text = 'Priemer';
            app.Menu2DOdchylka = uimenu(app.MenuFunkcie2D);
            app.Menu2DOdchylka.MenuSelectedFcn = createCallbackFcn(app, @Menu2DOdchylkaSelected, true);
            app.Menu2DOdchylka.Text = 'Odchýlka';
            app.Menu2DVariabilnost = uimenu(app.MenuFunkcie2D);
            app.Menu2DVariabilnost.MenuSelectedFcn = createCallbackFcn(app, @Menu2DVariabilnostSelected, true);
            app.Menu2DVariabilnost.Text = 'Variabilnosť';
            app.Menu2DSikmost = uimenu(app.MenuFunkcie2D);
            app.Menu2DSikmost.MenuSelectedFcn = createCallbackFcn(app, @Menu2DSikmostSelected, true);
            app.Menu2DSikmost.Text = 'Šikmosť';
            app.Menu2DSpicatost = uimenu(app.MenuFunkcie2D);
            app.Menu2DSpicatost.MenuSelectedFcn = createCallbackFcn(app, @Menu2DSpicatostSelected, true);
            app.Menu2DSpicatost.Text = 'Špičatosť';
            app.Menu2DHurst = uimenu(app.MenuFunkcie2D);
            app.Menu2DHurst.MenuSelectedFcn = createCallbackFcn(app, @Menu2DHurstSelected, true);
            app.Menu2DHurst.Text = 'Hurst';
            app.Menu2DEntropia = uimenu(app.MenuFunkcie2D);
            app.Menu2DEntropia.MenuSelectedFcn = createCallbackFcn(app, @Menu2DEntropiaSelected, true);
            app.Menu2DEntropia.Text = 'Entropia';
            app.Menu2DEnergia = uimenu(app.MenuFunkcie2D);
            app.Menu2DEnergia.MenuSelectedFcn = createCallbackFcn(app, @Menu2DEnergiaSelected, true);
            app.Menu2DEnergia.Text = 'Energia';
            app.Menu2DAutoregresia = uimenu(app.MenuFunkcie2D);
            app.Menu2DAutoregresia.MenuSelectedFcn = createCallbackFcn(app, @Menu2DAutoregresiaSelected, true);
            app.Menu2DAutoregresia.Text = 'Autoregresia';
            app.Menu2DKorelacia = uimenu(app.MenuFunkcie2D);
            app.Menu2DKorelacia.MenuSelectedFcn = createCallbackFcn(app, @Menu2DKorelaciaSelected, true);
            app.Menu2DKorelacia.Text = 'Korelácia';
            app.Menu2DDivergencia = uimenu(app.MenuFunkcie2D);
            app.Menu2DDivergencia.MenuSelectedFcn = createCallbackFcn(app, @Menu2DDivergenciaSelected, true);
            app.Menu2DDivergencia.Text = 'Divergencia';
            app.Funkcie3DMenu = uimenu(app.UIFigure);
            app.Funkcie3DMenu.Text = 'Funkcie 3D';
            app.Menu3DPriemer = uimenu(app.Funkcie3DMenu);
            app.Menu3DPriemer.MenuSelectedFcn = createCallbackFcn(app, @Menu3DPriemerSelected, true);
            app.Menu3DPriemer.Text = 'Priemer 3D';
            app.Menu3DPriemer.Tag = 'priemer';
            app.Menu3DOdchylka = uimenu(app.Funkcie3DMenu);
            app.Menu3DOdchylka.MenuSelectedFcn = createCallbackFcn(app, @Menu3DOdchylkaSelected, true);
            app.Menu3DOdchylka.Text = 'Odchýlka 3D';
            app.Menu3DOdchylka.Tag = 'odchylka';
            app.Menu3DVariabilnost = uimenu(app.Funkcie3DMenu);
            app.Menu3DVariabilnost.MenuSelectedFcn = createCallbackFcn(app, @Menu3DVariabilnostSelected, true);
            app.Menu3DVariabilnost.Text = 'Variabilnosť 3D';
            app.Menu3DSikmost = uimenu(app.Funkcie3DMenu);
            app.Menu3DSikmost.MenuSelectedFcn = createCallbackFcn(app, @Menu3DSikmostSelected, true);
            app.Menu3DSikmost.Text = 'Šikmosť 3D';
            app.Menu3DSpicatost = uimenu(app.Funkcie3DMenu);
            app.Menu3DSpicatost.MenuSelectedFcn = createCallbackFcn(app, @Menu3DSpicatostSelected, true);
            app.Menu3DSpicatost.Text = 'Špičatosť 3D';
            app.Menu3DHurst = uimenu(app.Funkcie3DMenu);
            app.Menu3DHurst.MenuSelectedFcn = createCallbackFcn(app, @Menu3DHurstSelected, true);
            app.Menu3DHurst.Text = 'Hurst 3D';
            app.Menu3DEntropia = uimenu(app.Funkcie3DMenu);
            app.Menu3DEntropia.MenuSelectedFcn = createCallbackFcn(app, @Menu3DEntropiaSelected, true);
            app.Menu3DEntropia.Text = 'Entropia 3D';
            app.Menu3DEnergia = uimenu(app.Funkcie3DMenu);
            app.Menu3DEnergia.MenuSelectedFcn = createCallbackFcn(app, @Menu3DEnergiaSelected, true);
            app.Menu3DEnergia.Text = 'Energia 3D';
            app.Menu3DAutoregresia = uimenu(app.Funkcie3DMenu);
            app.Menu3DAutoregresia.MenuSelectedFcn = createCallbackFcn(app, @Menu3DAutoregresiaSelected, true);
            app.Menu3DAutoregresia.Text = 'Autoreresia 3D';
            app.Menu3DKorelacia = uimenu(app.Funkcie3DMenu);
            app.Menu3DKorelacia.MenuSelectedFcn = createCallbackFcn(app, @Menu3DKorelaciaSelected, true);
            app.Menu3DKorelacia.Text = 'Korelácia 3D';
            app.Menu3DDivergencia = uimenu(app.Funkcie3DMenu);
            app.Menu3DDivergencia.MenuSelectedFcn = createCallbackFcn(app, @Menu3DDivergenciaSelected, true);
            app.Menu3DDivergencia.Text = 'Divergencia 3D';
            app.UIAxes_Kumulativ_Pakety = uiaxes(app.UIFigure);
            title(app.UIAxes_Kumulativ_Pakety, 'Kumulatívne medzery medzi paketmi')
            xlabel(app.UIAxes_Kumulativ_Pakety, 'X')
            ylabel(app.UIAxes_Kumulativ_Pakety, 'Y')
            zlabel(app.UIAxes_Kumulativ_Pakety, 'Z')
            app.UIAxes_Kumulativ_Pakety.ButtonDownFcn = createCallbackFcn(app, @PCAPfileMenuSelected, true);
            app.UIAxes_Te = uiaxes(app.UIFigure);
            title(app.UIAxes_Te, 'Medzery medzi paketmi')
            xlabel(app.UIAxes_Te, 'X')
            ylabel(app.UIAxes_Te, 'Y')
            zlabel(app.UIAxes_Te, 'Z')
            app.UIAxes_KB = uiaxes(app.UIFigure);
            title(app.UIAxes_KB, 'Prírastky informácií v kB')
            xlabel(app.UIAxes_KB, 'X')
            ylabel(app.UIAxes_KB, 'Y')
            zlabel(app.UIAxes_KB, 'Z')
            app.UIAxes_Prirastky = uiaxes(app.UIFigure);
            title(app.UIAxes_Prirastky, 'Prírastky paketov v čase')
            xlabel(app.UIAxes_Prirastky, 'X')
            ylabel(app.UIAxes_Prirastky, 'Y')
            zlabel(app.UIAxes_Prirastky, 'Z')
            app.PriemerLabel = uilabel(app.UIFigure);
            app.PriemerLabel.Text = 'Priemer časov (mikrosekundy):';
            app.tandardnodchlkaLabel = uilabel(app.UIFigure);
            app.tandardnodchlkaLabel.Text = 'Štandardná odchýlka časov:';
            app.PriemerLabel_2 = uilabel(app.UIFigure);
            app.PriemerLabel_2.Text = 'Priemer paketov:';
            app.tandardnodchlkaLabel_3 = uilabel(app.UIFigure);
            app.tandardnodchlkaLabel_3.Text = 'Štandardná odchýlka paketov:';
            app.tandardnodchlkaLabel_2 = uilabel(app.UIFigure);
            app.tandardnodchlkaLabel_2.Text = 'Default veľkosť sample window:';
            app.EditField_ChangeSampleWindow = uieditfield(app.UIFigure, 'numeric');
            app.EditField_ChangeSampleWindow.Limits = [0 Inf];
            app.EditField_ChangeSampleWindow.Value = 1;
            app.EditField_SampleWindow = uieditfield(app.UIFigure, 'text');
            app.EditField_SampleWindow.Placeholder = '0';
            app.EditField_SampleWindow.Value = '3000';
            app.EditField_StandardDeviation_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_StandardDeviation_2.Placeholder = '0';
            app.EditField_StandardDeviation = uieditfield(app.UIFigure, 'text');
            app.EditField_StandardDeviation.Placeholder = '0';
            app.EditField_MeanPackets = uieditfield(app.UIFigure, 'text');
            app.EditField_MeanPackets.Placeholder = '0';
            app.EditField_MeanOfTimes = uieditfield(app.UIFigure, 'text');
            app.EditField_MeanOfTimes.Placeholder = '0';
            app.NovvekospresamplewindowLabel = uilabel(app.UIFigure);
            app.NovvekospresamplewindowLabel.Text = 'Aktuálna veľkosť pre sample window';
            app.VekosnatanchdtLabel = uilabel(app.UIFigure);
            app.VekosnatanchdtLabel.Text = 'Veľkosť načítaných dát:';
            app.EditField_LengthOfData = uieditfield(app.UIFigure, 'text');
            app.EditField_LengthOfData.Placeholder = '0';
            app.EditField_NacitanySubor = uieditfield(app.UIFigure, 'text');
            app.EditField_NacitanySubor.Value = 'žiadny';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Časy'; 'Pakety'};
            app.ButtonUlozFigure_b = uibutton(app.UIFigure, 'push');
            app.ButtonUlozFigure_b.ButtonPushedFcn = createCallbackFcn(app, @ButtonUlozFigure_bPushed, true);
            app.ButtonUlozFigure_b.Icon = fullfile(pathToMLAPP, 'icon_download.png');
            app.ButtonUlozFigure_b.Tooltip = {'Ulož prírastky informácií v kB ako figure'};
            app.ButtonUlozFigure_b.Text = 'Figure';
            app.EditField_Histogram_b = uieditfield(app.UIFigure, 'numeric');
            app.EditField_Histogram_b.Limits = [0 100];
            app.EditField_Histogram_b.Value = 10;
            app.ButtonZobrazHistogram_b = uibutton(app.UIFigure, 'push');
            app.ButtonZobrazHistogram_b.ButtonPushedFcn = createCallbackFcn(app, @ButtonZobrazHistogram_bPushed, true);
            app.ButtonZobrazHistogram_b.Tooltip = {'Ulož prírastky paketov v čase ako figure'};
            app.ButtonZobrazHistogram_b.Text = 'Zobraz histogram';
            app.EditField_Histogram_a = uieditfield(app.UIFigure, 'numeric');
            app.EditField_Histogram_a.Limits = [0 100];
            app.EditField_Histogram_a.Value = 10;
            app.ButtonZobrazHistogram_a = uibutton(app.UIFigure, 'push');
            app.ButtonZobrazHistogram_a.ButtonPushedFcn = createCallbackFcn(app, @ButtonZobrazHistogram_aPushed, true);
            app.ButtonZobrazHistogram_a.Tooltip = {'Ulož prírastky paketov v čase ako figure'};
            app.ButtonZobrazHistogram_a.Text = 'Zobraz histogram';
            app.ButtonUlozFigure_a = uibutton(app.UIFigure, 'push');
            app.ButtonUlozFigure_a.ButtonPushedFcn = createCallbackFcn(app, @ButtonUlozFigure_aPushed, true);
            app.ButtonUlozFigure_a.Icon = fullfile(pathToMLAPP, 'icon_download.png');
            app.ButtonUlozFigure_a.Tooltip = {'Ulož prírastky paketov v čase ako figure'};
            app.ButtonUlozFigure_a.Text = 'Figure ';
            app.PrepocitajButton = uibutton(app.UIFigure, 'push');
            app.PrepocitajButton.ButtonPushedFcn = createCallbackFcn(app, @PrepocitajButtonPushed, true);
            app.PrepocitajButton.Text = 'Prepočítať';
            app.Panel_UpravaVstupu = uipanel(app.UIFigure);
            app.Panel_UpravaVstupu.Title = 'Úprava vstupu pre nový IMPORT';
            app.Panel_UpravaVstupu.Visible = 'off';
            app.Panel_UIAxes = uiaxes(app.Panel_UpravaVstupu);
            title(app.Panel_UIAxes, 'Prírastky pre spracovanie')
            xlabel(app.Panel_UIAxes, 'X')
            ylabel(app.Panel_UIAxes, 'Y')
            zlabel(app.Panel_UIAxes, 'Z')
            app.VystrihniButton = uibutton(app.Panel_UpravaVstupu, 'push');
            app.VystrihniButton.ButtonPushedFcn = createCallbackFcn(app, @VystrihniButtonPushed, true);
            app.VystrihniButton.Text = 'Vystrihnúť';
            app.Panel_StrihajOd = uieditfield(app.Panel_UpravaVstupu, 'numeric');
            app.Panel_StrihajOd.Limits = [1 Inf];
            app.Panel_StrihajOd.ValueDisplayFormat = '%.0f';
            app.Panel_StrihajOd.Value = 1;
            app.Panel_dvojbodka = uilabel(app.Panel_UpravaVstupu);
            app.Panel_dvojbodka.Text = ':';
            app.Panel_StrihajPo = uieditfield(app.Panel_UpravaVstupu, 'numeric');
            app.Panel_StrihajPo.Limits = [1 Inf];
            app.Panel_StrihajPo.ValueDisplayFormat = '%.0f';
            app.Panel_StrihajPo.Value = 1000000;
            app.UkoniButton = uibutton(app.Panel_UpravaVstupu, 'push');
            app.UkoniButton.ButtonPushedFcn = createCallbackFcn(app, @UkoniButtonPushed, true);
            app.UkoniButton.Text = 'Ukončiť';
            app.NacitatVstupButton = uibutton(app.Panel_UpravaVstupu, 'push');
            app.NacitatVstupButton.ButtonPushedFcn = createCallbackFcn(app, @NacitatVstupButtonPushed, true);
            app.NacitatVstupButton.Text = 'Načítať vstup';
            app.UlozkBPrirastkyButton = uibutton(app.UIFigure, 'push');
            app.UlozkBPrirastkyButton.ButtonPushedFcn = createCallbackFcn(app, @UlozkBPrirastkyButtonPushed, true);
            app.UlozkBPrirastkyButton.Icon = fullfile(pathToMLAPP, 'icon_download.png');
            app.UlozkBPrirastkyButton.Tooltip = {'Ulož prírastky informácií v kB ako .mat súbor'};
            app.UlozkBPrirastkyButton.Text = 'Prírastky kB';
            app.UlozPrirastkyCasuButton = uibutton(app.UIFigure, 'push');
            app.UlozPrirastkyCasuButton.ButtonPushedFcn = createCallbackFcn(app, @UlozPrirastkyCasuButtonPushed, true);
            app.UlozPrirastkyCasuButton.Icon = fullfile(pathToMLAPP, 'icon_download.png');
            app.UlozPrirastkyCasuButton.Tooltip = {'Ulož prírastky paketov v čase ako .mat súbor'};
            app.UlozPrirastkyCasuButton.Text = 'Prírastky času';
            app.NatansborEditFieldLabel = uilabel(app.UIFigure);
            app.NatansborEditFieldLabel.Text = 'Načítaný súbor:';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Resetovať';
            app.UlozPacketLengthButton = uibutton(app.UIFigure, 'push');
            app.UlozPacketLengthButton.ButtonPushedFcn = createCallbackFcn(app, @UlozPacketLengthButtonPushed, true);
            app.UlozPacketLengthButton.Icon = fullfile(pathToMLAPP, 'icon_download.png');
            app.UlozPacketLengthButton.Tooltip = {'Ulož vstupný vektor veľkostí paketov ako .mat súbor'};
            app.UlozPacketLengthButton.Text = 'Veľkosti paketov';
            app.UlozTimestampButton = uibutton(app.UIFigure, 'push');
            app.UlozTimestampButton.ButtonPushedFcn = createCallbackFcn(app, @UlozTimestampButtonPushed, true);
            app.UlozTimestampButton.Icon = fullfile(pathToMLAPP, 'icon_download.png');
            app.UlozTimestampButton.Tooltip = {'Ulož vektor príchody časov ako .mat súbor'};
            app.UlozTimestampButton.Text = 'Príchody časov';
            app.Panel_3DFunkcie = uipanel(app.UIFigure);
            app.Panel_3DFunkcie.Title = '3D funkcie';
            app.Panel_3DFunkcie.Visible = 'off';
            app.UIAxes3DFunkcia = uiaxes(app.Panel_3DFunkcie);
            title(app.UIAxes3DFunkcia, 'Title')
            xlabel(app.UIAxes3DFunkcia, 'X')
            ylabel(app.UIAxes3DFunkcia, 'Y')
            zlabel(app.UIAxes3DFunkcia, 'Z')
            app.UIAxes3DViewFunkcia = uiaxes(app.Panel_3DFunkcie);
            title(app.UIAxes3DViewFunkcia, 'Title')
            xlabel(app.UIAxes3DViewFunkcia, 'X')
            ylabel(app.UIAxes3DViewFunkcia, 'Y')
            zlabel(app.UIAxes3DViewFunkcia, 'Z')
            app.Button3DFunkcie_Ukoncit = uibutton(app.Panel_3DFunkcie, 'push');
            app.Button3DFunkcie_Ukoncit.ButtonPushedFcn = createCallbackFcn(app, @Button3DFunkcie_UkoncitPushed, true);
            app.Button3DFunkcie_Ukoncit.Text = 'Ukončiť';
            app.LabelNacitanySuborInfo = uilabel(app.Panel_3DFunkcie);
            app.LabelNacitanySuborInfo.Text = 'Načítaný súbor:';
            app.LabelNacitanySubor = uilabel(app.Panel_3DFunkcie);
            app.LabelNacitanySubor.Text = 'žiadny';
            app.ButtonNacitatVstup3D = uibutton(app.Panel_3DFunkcie, 'push');
            app.ButtonNacitatVstup3D.ButtonPushedFcn = createCallbackFcn(app, @ButtonNacitatVstup3DPushed, true);
            app.ButtonNacitatVstup3D.Text = 'Načítať vstup';
            app.Hodnota1EditFieldLabel = uilabel(app.Panel_3DFunkcie);
            app.Hodnota1EditFieldLabel.Text = 'Hodnota 1';
            app.EditField_Hodnota1 = uieditfield(app.Panel_3DFunkcie, 'numeric');
            app.EditField_Hodnota1.Limits = [0 Inf];
            app.EditField_Hodnota1.Value = 512;
            app.Zadajtehodnotyprevpoetmin1max5Label = uilabel(app.Panel_3DFunkcie);
            app.Zadajtehodnotyprevpoetmin1max5Label.Text = 'Zadajte hodnoty pre výpočet (min 1 - max 5):';
            app.Hodnota2EditFieldLabel = uilabel(app.Panel_3DFunkcie);
            app.Hodnota2EditFieldLabel.Text = 'Hodnota 2';
            app.EditField_Hodnota2 = uieditfield(app.Panel_3DFunkcie, 'numeric');
            app.EditField_Hodnota2.Limits = [0 Inf];
            app.EditField_Hodnota2.Value = 1024;
            app.Hodnota3EditFieldLabel = uilabel(app.Panel_3DFunkcie);
            app.Hodnota3EditFieldLabel.Text = 'Hodnota 3';
            app.EditField_Hodnota3 = uieditfield(app.Panel_3DFunkcie, 'numeric');
            app.EditField_Hodnota3.Limits = [0 Inf];
            app.EditField_Hodnota3.Value = 2048;
            app.Hodnota4EditFieldLabel = uilabel(app.Panel_3DFunkcie);
            app.Hodnota4EditFieldLabel.Text = 'Hodnota 4';
            app.EditField_Hodnota4 = uieditfield(app.Panel_3DFunkcie, 'numeric');
            app.EditField_Hodnota4.Limits = [0 Inf];
            app.EditField_Hodnota4.Value = 3000;
            app.Hodnota5EditFieldLabel = uilabel(app.Panel_3DFunkcie);
            app.Hodnota5EditFieldLabel.Text = 'Hodnota 5';
            app.EditField_Hodnota5 = uieditfield(app.Panel_3DFunkcie, 'numeric');
            app.EditField_Hodnota5.Limits = [0 Inf];
            app.EditField_Hodnota5.Value = 4000;
            app.ButtonVypocitat3D = uibutton(app.Panel_3DFunkcie, 'push');
            app.ButtonVypocitat3D.ButtonPushedFcn = createCallbackFcn(app, @ButtonVypocitat3DPushed, true);
            app.ButtonVypocitat3D.Text = 'Vypočítať';
            app.EditFieldVynasob3D = uieditfield(app.Panel_3DFunkcie, 'numeric');
            app.EditFieldVynasob3D.Limits = [0 1000];
            app.EditFieldVynasob3D.Value = 2;
            app.ButtonVynasobParameter3D = uibutton(app.Panel_3DFunkcie, 'push');
            app.ButtonVynasobParameter3D.ButtonPushedFcn = createCallbackFcn(app, @ButtonVynasobParameter3DPushed, true);
            app.ButtonVynasobParameter3D.Text = 'Vynásob parameter';
            app.Panel_2DFunkcie = uipanel(app.UIFigure);
            app.Panel_2DFunkcie.Title = '2D funkcie';
            app.Panel_2DFunkcie.Visible = 'off';
            app.UIAxes2DFunkcia = uiaxes(app.Panel_2DFunkcie);
            title(app.UIAxes2DFunkcia, 'Title')
            xlabel(app.UIAxes2DFunkcia, 'X')
            ylabel(app.UIAxes2DFunkcia, 'Y')
            zlabel(app.UIAxes2DFunkcia, 'Z')
            app.UIAxes2DFunkciaView = uiaxes(app.Panel_2DFunkcie);
            title(app.UIAxes2DFunkciaView, 'Title')
            xlabel(app.UIAxes2DFunkciaView, 'X')
            ylabel(app.UIAxes2DFunkciaView, 'Y')
            zlabel(app.UIAxes2DFunkciaView, 'Z')
            app.Button2DFunkcie_Ukoncit = uibutton(app.Panel_2DFunkcie, 'push');
            app.Button2DFunkcie_Ukoncit.ButtonPushedFcn = createCallbackFcn(app, @Button2DFunkcie_UkoncitPushed, true);
            app.Button2DFunkcie_Ukoncit.Text = 'Ukončiť';
            app.Label2DNacitanySuborInfo = uilabel(app.Panel_2DFunkcie);
            app.Label2DNacitanySuborInfo.Text = 'Načítaný súbor:';
            app.Label2DNacitanySubor = uilabel(app.Panel_2DFunkcie);
            app.Label2DNacitanySubor.Text = 'žiadny';
            app.ButtonNacitatVstup2D = uibutton(app.Panel_2DFunkcie, 'push');
            app.ButtonNacitatVstup2D.ButtonPushedFcn = createCallbackFcn(app, @ButtonNacitatVstup2DPushed, true);
            app.ButtonNacitatVstup2D.Text = 'Načítať vstup';
            app.HodnotaLabel = uilabel(app.Panel_2DFunkcie);
            app.HodnotaLabel.Text = 'Hodnota';
            app.EditField2D_Hodnota = uieditfield(app.Panel_2DFunkcie, 'numeric');
            app.EditField2D_Hodnota.Limits = [0 Inf];
            app.EditField2D_Hodnota.Value = 512;
            app.LabelHodnotaOkna2D = uilabel(app.Panel_2DFunkcie);
            app.LabelHodnotaOkna2D.Text = 'Zadajte hodnotu okna pre výpočet:';
            app.ButtonVypocitat2D = uibutton(app.Panel_2DFunkcie, 'push');
            app.ButtonVypocitat2D.ButtonPushedFcn = createCallbackFcn(app, @ButtonVypocitat2DPushed, true);
            app.ButtonVypocitat2D.Text = 'Vypočítať';
            app.ButtonVynasobParameter2D = uibutton(app.Panel_2DFunkcie, 'push');
            app.ButtonVynasobParameter2D.ButtonPushedFcn = createCallbackFcn(app, @ButtonVynasobParameter2DPushed, true);
            app.ButtonVynasobParameter2D.Text = 'Vynásob parameter';
            app.ButtonUlozParameter2D = uibutton(app.Panel_2DFunkcie, 'push');
            app.ButtonUlozParameter2D.ButtonPushedFcn = createCallbackFcn(app, @ButtonUlozParameter2DPushed, true);
            app.ButtonUlozParameter2D.Icon = fullfile(pathToMLAPP, 'icon_download.png');
            app.ButtonUlozParameter2D.Text = 'Parameter';
            app.EditFieldVynasob2D = uieditfield(app.Panel_2DFunkcie, 'numeric');
            app.EditFieldVynasob2D.Limits = [0 1000];
            app.EditFieldVynasob2D.Value = 2;
            app.Panel_DetaielnejsiVystup = uipanel(app.UIFigure);
            app.Panel_DetaielnejsiVystup.Title = 'Detailnejší graf o prírastkoch';
            app.Panel_DetaielnejsiVystup.Visible = 'off';
            app.UIAxesDetail = uiaxes(app.Panel_DetaielnejsiVystup);
            title(app.UIAxesDetail, 'Prírastky')
            xlabel(app.UIAxesDetail, 'X')
            ylabel(app.UIAxesDetail, 'Y')
            zlabel(app.UIAxesDetail, 'Z')
            app.ButtonDetail_Ukoncit = uibutton(app.Panel_DetaielnejsiVystup, 'push');
            app.ButtonDetail_Ukoncit.ButtonPushedFcn = createCallbackFcn(app, @ButtonDetail_UkoncitPushed, true);
            app.ButtonDetail_Ukoncit.Text = 'Ukončiť';
            app.ButtonDetail_NacitatVstup = uibutton(app.Panel_DetaielnejsiVystup, 'push');
            app.ButtonDetail_NacitatVstup.ButtonPushedFcn = createCallbackFcn(app, @ButtonDetail_NacitatVstupPushed, true);
            app.ButtonDetail_NacitatVstup.Text = 'Načítať naslotované dáta';
            app.LabelDetailInfo_Vstup = uilabel(app.Panel_DetaielnejsiVystup);
            app.LabelDetailInfo_Vstup.Text = 'Načítaný vstup:';
            app.LabelDetail_NacitanySubor = uilabel(app.Panel_DetaielnejsiVystup);
            app.LabelDetail_NacitanySubor.Text = 'žiadny';
            app.ZadajtevekosoknaprepohyblivodchlkuLabel = uilabel(app.Panel_DetaielnejsiVystup);
            app.ZadajtevekosoknaprepohyblivodchlkuLabel.Text = 'Zadajte veľkosť okna pre pohyblivú odchýlku:';
            app.EditFieldDetail_Odchylka = uieditfield(app.Panel_DetaielnejsiVystup, 'numeric');
            app.EditFieldDetail_Odchylka.Limits = [1 Inf];
            app.EditFieldDetail_Odchylka.Value = 1;
            app.ZadajtevekosoknaprepohyblivpriemerEditFieldLabel = uilabel(app.Panel_DetaielnejsiVystup);
            app.ZadajtevekosoknaprepohyblivpriemerEditFieldLabel.Text = 'Zadajte veľkosť okna pre pohyblivý priemer:';
            app.EditFieldDetail_Priemer = uieditfield(app.Panel_DetaielnejsiVystup, 'numeric');
            app.EditFieldDetail_Priemer.Limits = [1 Inf];
            app.EditFieldDetail_Priemer.Value = 1;
            app.ZadajtevekosoknapreHHaDHsigmyEditFieldLabel = uilabel(app.Panel_DetaielnejsiVystup);
            app.ZadajtevekosoknapreHHaDHsigmyEditFieldLabel.Text = 'Zadajte veľkosť okna pre HH a DH sigmy:';
            app.EditFieldDetail_Sigma = uieditfield(app.Panel_DetaielnejsiVystup, 'numeric');
            app.EditFieldDetail_Sigma.Limits = [1 Inf];
            app.EditFieldDetail_Sigma.Value = 1;
            app.LabelDetail_CheckBox = uilabel(app.Panel_DetaielnejsiVystup);
            app.LabelDetail_CheckBox.Text = 'Zaškrtnite čo chcete zobraziť na grafe:';
            app.CheckBoxDetail_Priemer = uicheckbox(app.Panel_DetaielnejsiVystup);
            app.CheckBoxDetail_Priemer.ValueChangedFcn = createCallbackFcn(app, @CheckBoxDetail_PriemerValueChanged, true);
            app.CheckBoxDetail_Priemer.Text = 'Kĺzavý priemer';
            app.CheckBoxDetail_Odchylka = uicheckbox(app.Panel_DetaielnejsiVystup);
            app.CheckBoxDetail_Odchylka.ValueChangedFcn = createCallbackFcn(app, @CheckBoxDetail_OdchylkaValueChanged, true);
            app.CheckBoxDetail_Odchylka.Text = 'Kĺzavá štandardná odchýlka';
            app.CheckBoxDetail_HH = uicheckbox(app.Panel_DetaielnejsiVystup);
            app.CheckBoxDetail_HH.ValueChangedFcn = createCallbackFcn(app, @CheckBoxDetail_HHValueChanged, true);
            app.CheckBoxDetail_HH.Text = 'Sigma HH (horná hranica)';
            app.CheckBoxDetail_DH = uicheckbox(app.Panel_DetaielnejsiVystup);
            app.CheckBoxDetail_DH.ValueChangedFcn = createCallbackFcn(app, @CheckBoxDetail_DHValueChanged, true);
            app.CheckBoxDetail_DH.Text = 'Sigma DH (dolná hranica)';
            app.ButtonDetail_Vypocitat = uibutton(app.Panel_DetaielnejsiVystup, 'push');
            app.ButtonDetail_Vypocitat.ButtonPushedFcn = createCallbackFcn(app, @ButtonDetail_VypocitatPushed, true);
            app.ButtonDetail_Vypocitat.Text = 'Vypočítať';
            app.ButtonDetail_Resetovat = uibutton(app.Panel_DetaielnejsiVystup, 'push');
            app.ButtonDetail_Resetovat.ButtonPushedFcn = createCallbackFcn(app, @ButtonDetail_ResetovatPushed, true);
            app.ButtonDetail_Resetovat.Text = 'Resetovať';
            app.ButtonDetail_ClearGraf = uibutton(app.Panel_DetaielnejsiVystup, 'push');
            app.ButtonDetail_ClearGraf.ButtonPushedFcn = createCallbackFcn(app, @ButtonDetail_ClearGrafPushed, true);
            app.ButtonDetail_ClearGraf.Text = 'Vyčistiť graf';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end